classdef QuarterCarModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        SimulateButton                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        PassiveSuspensionTab           matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        Y3Plot                         matlab.ui.control.UIAxes
        Y2Plot                         matlab.ui.control.UIAxes
        Y1Plot                         matlab.ui.control.UIAxes
        ZPlot                          matlab.ui.control.UIAxes
        GridLayout7                    matlab.ui.container.GridLayout
        GenerateNoiseCheckBox          matlab.ui.control.CheckBox
        DropDown                       matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        InputSignalRoadProfileLabel    matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        RandomizeButton                matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        M1WheelsSlider                 matlab.ui.control.Slider
        M1WheelsSliderLabel            matlab.ui.control.Label
        C3SeatbackfrictionSlider       matlab.ui.control.Slider
        C3SeatbackfrictionSliderLabel  matlab.ui.control.Label
        C2SuspensionSlider             matlab.ui.control.Slider
        C2SuspensionSliderLabel        matlab.ui.control.Label
        C1SeatSlider                   matlab.ui.control.Slider
        C1SeatSliderLabel              matlab.ui.control.Label
        K3Tyrex1000Slider              matlab.ui.control.Slider
        K3Tyrex1000SliderLabel         matlab.ui.control.Label
        K2Suspensionx1000Slider        matlab.ui.control.Slider
        K2Suspensionx1000SliderLabel   matlab.ui.control.Label
        K1Seatx1000Slider              matlab.ui.control.Slider
        K1Seatx1000SliderLabel         matlab.ui.control.Label
        M3SeatDriverSlider             matlab.ui.control.Slider
        M3SeatDriverSliderLabel        matlab.ui.control.Label
        M2ChassisSlider                matlab.ui.control.Slider
        M2ChassisSliderLabel           matlab.ui.control.Label
        ModelParametersLabel           matlab.ui.control.Label
        ActiveSuspensionTab            matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        GridLayout14                   matlab.ui.container.GridLayout
        FAPlot                         matlab.ui.control.UIAxes
        RMSAPlot                       matlab.ui.control.UIAxes
        YAPlot                         matlab.ui.control.UIAxes
        ZAPlot                         matlab.ui.control.UIAxes
        GridLayout10                   matlab.ui.container.GridLayout
        GridLayout13                   matlab.ui.container.GridLayout
        ChangeinputsignalconfigurationinthePassiveSuspensiontabLabel  matlab.ui.control.Label
        InputSignalRoadProfileLabel_2  matlab.ui.control.Label
        GridLayout12                   matlab.ui.container.GridLayout
        RandomizeButton_2              matlab.ui.control.Button
        ResetButton_2                  matlab.ui.control.Button
        SaturationSlider               matlab.ui.control.Slider
        SaturationSliderLabel          matlab.ui.control.Label
        KdSlider                       matlab.ui.control.Slider
        KdSliderLabel                  matlab.ui.control.Label
        KiSlider                       matlab.ui.control.Slider
        KiSliderLabel                  matlab.ui.control.Label
        KpSlider                       matlab.ui.control.Slider
        KpSliderLabel                  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        ControllerParametersLabel      matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        ChangeallmodelparametersinthePassiveSuspensiontabLabel  matlab.ui.control.Label
        ModelParametersLabel_2         matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        DOFQuarterCarModelbyMSMoudallalLabel  matlab.ui.control.Label
    end

    
%   QuarterCarModel.mlapp
%   File written by Mohamad Said Moudallal
%   Email: mohamad@moudallal.com
%   This app presents an interface to interact with the various parameters
%   that define a mathematical model of a quarter of a car's suspension
%   system.
%   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%
%

    % Define private variables

    properties (Access = private)
        % Initial system parameters
        M1 = 25;    % Unsprung mass 1: Wheel and axle
        M2 = 200;   % Sprung mass 1: Chassis
        M3 = 100;   % Sprung mass 2: Seat and driver
        
        k1 = 8000;      % Spring stiffness (seat-chassis)
        k2 = 15000;     % Spring stiffness (chassis-wheel)
        k3 = 25000;     % Spring stiffness (tyre)
        
        c1 = 1000;      % Damper coefficient (seat-chassis)
        c2 = 3700;      % Damper coefficient (chassis-wheel)
        c3 = 500;       % Damper coefficient (seat back friction)

        Kp = 1920;      % Proportional gain
        Ki = 25000;     % Integrator gain
        Kd = 2.5;       % Differentiator gain
        sat = 3000;     % Absolute saturation limit

        endtime = 20; % Simulation runtime (seconds)

        noise_enabled = 0;

        % Step response = 1
        % Sine wave = 2
        % Speed bump = 3
        % Pothole = 4
        % Ramp = 5
        % Decline = 6
        input_signal = 1;
        
        % Passive = 1
        % Active = 2
        simulation_mode = 1;

    end
    
    methods (Access = private)
        
        function [yy, tt] = simulateModel(app)

            M1 = app.M1;
            M2 = app.M2;
            M3 = app.M3;
            
            k1 = app.k1;
            k2 = app.k2;
            k3 = app.k3;
            
            c1 = app.c1;
            c2 = app.c2;
            c3 = app.c3;

            % Define state-space matrices
            % Passive model
            A = [0              0               0       1           0                   0;
                 0              0               0       0           1                   0;
                 0              0               0       0           0                   1;
                 -(k2+k3)/M1    k2/M1           0       -c2/M1      c2/M1               0;
                 k2/M2          -(k1+k2)/M2     k1/M2   c2/M2       -(c1+c2+c3)/M2      (c1+c3)/M2;
                 0              k1/M3           -k1/M3  0           (c1+c3)/M3          -(c1+c3)/M3];        
            B = [0; 0; 0; k3/M1; 0; 0];            
            C = [1 0 0 0 0 0;
                 0 1 0 0 0 0;
                 0 0 1 0 0 0]; 
            D = [0; 0; 0];

            % Active model
            Aa = A;
            Ba = [0 0;
                  0 0;
                  0 0;
                  k3/M1 -1/M1;
                  0 1/M2;
                  0 0];
            Ca = eye(6);
            Da = zeros(6, 2);

            simIn = Simulink.SimulationInput("quarter_car_model");
            simIn = simIn.setVariable('A', A);
            simIn = simIn.setVariable('B', B);
            simIn = simIn.setVariable('C', C);
            simIn = simIn.setVariable('D', D);
            simIn = simIn.setVariable('Aa', Aa);
            simIn = simIn.setVariable('Ba', Ba);
            simIn = simIn.setVariable('Ca', Ca);
            simIn = simIn.setVariable('Da', Da);
            
            simIn = simIn.setVariable('Kp', app.Kp);
            simIn = simIn.setVariable('Ki', app.Ki);
            simIn = simIn.setVariable('Kd', app.Kd);
            simIn = simIn.setVariable('sat', app.sat);

            simIn = simIn.setVariable('noise_enabled', app.noise_enabled);
            simIn = simIn.setVariable('input_signal', app.input_signal);

            simIn = simIn.setVariable('endtime', app.endtime);

            assignin('base', 'endtime', app.endtime);
            assignin('base', 'input_signal', app.input_signal);
            assignin('base', 'noise_enabled', app.noise_enabled);
            assignin('base', 'A', A);
            assignin('base', 'B', B);
            assignin('base', 'C', C);
            assignin('base', 'D', D);
            assignin('base', 'Aa', Aa);
            assignin('base', 'Ba', Ba);
            assignin('base', 'Ca', Ca);
            assignin('base', 'Da', Da);

            %simIn = simulink.compiler.configureForDeployment(simIn);

            simdata = sim(simIn);
            tt = simdata.simdata.time;
            yy = simdata.simdata.signals.values;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure);
            
            % Set initial slider values
            app.M1WheelsSlider.Value = app.M1;
            app.M2ChassisSlider.Value = app.M2;
            app.M3SeatDriverSlider.Value = app.M3;

            app.K1Seatx1000Slider.Value = app.k1 / 1000;
            app.K2Suspensionx1000Slider.Value = app.k2 / 1000;
            app.K3Tyrex1000Slider.Value = app.k3 / 1000;

            app.C1SeatSlider.Value = app.c1;
            app.C2SuspensionSlider.Value = app.c2;
            app.C3SeatbackfrictionSlider.Value = app.c3;

            app.KpSlider.Value = app.Kp;
            app.KiSlider.Value = app.Ki;
            app.KdSlider.Value = app.Kd;
            app.SaturationSlider.Value = app.sat;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Simulate model
            [yy, tt] = simulateModel(app);

            % Plot signals from Simulink output

            % Passive system
            plot(app.ZPlot, tt, yy(:, 1));
            plot(app.Y1Plot, tt, yy(:, 2));
            plot(app.Y2Plot, tt, yy(:, 3));
            plot(app.Y3Plot, tt, yy(:, 4));

            % Active system
            plot(app.ZAPlot, tt, yy(:, 1));
            plot(app.YAPlot, tt, yy(:, [5, 6, 7]));
            legend(app.YAPlot,'y1', 'y2', 'y3', 'Location', 'southeast', 'FontSize', 8);
            plot(app.RMSAPlot, tt, yy(:, 8));
            plot(app.FAPlot, tt, yy(:, 9));

            % Format plots for better visibility
            z_range = [min(yy(:, 1)) max(yy(:, 1))];
            app.ZPlot.YLim = z_range * 1.1;
            y1_range = [min(yy(:, 2)) max(yy(:, 2))];
            app.Y1Plot.YLim = y1_range * 1.1;
            y2_range = [min(yy(:, 3)) max(yy(:, 3))];
            app.Y2Plot.YLim = y2_range * 1.1;
            y3_range = [min(yy(:, 4)) max(yy(:, 4))];
            app.Y3Plot.YLim = y3_range * 1.1;

            app.ZAPlot.YLim = z_range * 1.1;
            rms_range = [min(yy(:, 8)) max(yy(:, 8))];
            app.RMSAPlot.YLim = rms_range * 1.1;
            fa_range = [min(yy(:, 9)) max(yy(:, 9))];
            app.FAPlot.YLim = fa_range * 1.1;
        end

        % Value changed function: M1WheelsSlider
        function M1WheelsSliderValueChanged(app, event)
            value = app.M1WheelsSlider.Value;
            app.M1 = value;
        end

        % Value changed function: M2ChassisSlider
        function M2ChassisSliderValueChanged(app, event)
            value = app.M2ChassisSlider.Value;
            app.M2 = value;
        end

        % Value changed function: M3SeatDriverSlider
        function M3SeatDriverSliderValueChanged(app, event)
            value = app.M3SeatDriverSlider.Value;
            app.M3 = value;
        end

        % Value changed function: K1Seatx1000Slider
        function K1Seatx1000SliderValueChanged(app, event)
            value = app.K1Seatx1000Slider.Value;
            app.k1 = value * 1000;
        end

        % Value changed function: K2Suspensionx1000Slider
        function K2Suspensionx1000SliderValueChanged(app, event)
            value = app.K2Suspensionx1000Slider.Value;
            app.k2 = value * 1000;
        end

        % Value changed function: K3Tyrex1000Slider
        function K3Tyrex1000SliderValueChanged(app, event)
            value = app.K3Tyrex1000Slider.Value;
            app.k3 = value * 1000;
        end

        % Value changed function: C1SeatSlider
        function C1SeatSliderValueChanged(app, event)
            value = app.C1SeatSlider.Value;
            app.c1 = value;
        end

        % Value changed function: C2SuspensionSlider
        function C2SuspensionSliderValueChanged(app, event)
            value = app.C2SuspensionSlider.Value;
            app.c2 = value;
        end

        % Value changed function: C3SeatbackfrictionSlider
        function C3SeatbackfrictionSliderValueChanged(app, event)
            value = app.C3SeatbackfrictionSlider.Value;
            app.c3 = value;
        end

        % Value changed function: GenerateNoiseCheckBox
        function GenerateNoiseCheckBoxValueChanged(app, event)
            value = app.GenerateNoiseCheckBox.Value;
            if (value)
                app.noise_enabled = 1;
            else
                app.noise_enabled = 0;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset slider values
            app.M1WheelsSlider.Value = 25;
            app.M2ChassisSlider.Value = 200;
            app.M3SeatDriverSlider.Value = 100;

            app.K1Seatx1000Slider.Value = 8000 / 1000;
            app.K2Suspensionx1000Slider.Value = 15000 / 1000;
            app.K3Tyrex1000Slider.Value = 25000 / 1000;

            app.C1SeatSlider.Value = 1000;
            app.C2SuspensionSlider.Value = 3700;
            app.C3SeatbackfrictionSlider.Value = 500;

            app.M1 = app.M1WheelsSlider.Value;
            app.M2 = app.M2ChassisSlider.Value;
            app.M3 = app.M3SeatDriverSlider.Value;

            app.k1 = app.K1Seatx1000Slider.Value * 1000;
            app.k2 = app.K2Suspensionx1000Slider.Value * 1000;
            app.k3 = app.K3Tyrex1000Slider.Value * 1000;

            app.c1 = app.C1SeatSlider.Value;
            app.c2 = app.C2SuspensionSlider.Value;
            app.c3 = app.C3SeatbackfrictionSlider.Value;
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            M1_range = app.M1WheelsSlider.Limits;
            M2_range = app.M2ChassisSlider.Limits;
            M3_range = app.M3SeatDriverSlider.Limits;
            k1_range = app.K1Seatx1000Slider.Limits;
            k2_range = app.K2Suspensionx1000Slider.Limits;
            k3_range = app.K3Tyrex1000Slider.Limits;
            c1_range = app.C1SeatSlider.Limits;
            c2_range = app.C2SuspensionSlider.Limits;
            c3_range = app.C3SeatbackfrictionSlider.Limits;

            app.M1WheelsSlider.Value = M1_range(1) + (M1_range(2) - M1_range(1)) * rand();
            app.M2ChassisSlider.Value = M2_range(1) + (M2_range(2) - M2_range(1)) * rand();
            app.M3SeatDriverSlider.Value = M3_range(1) + (M3_range(2) - M3_range(1)) * rand();

            app.K1Seatx1000Slider.Value = k1_range(1) + (k1_range(2) - k1_range(1)) * rand();
            app.K2Suspensionx1000Slider.Value = k2_range(1) + (k2_range(2) - k2_range(1)) * rand();
            app.K3Tyrex1000Slider.Value = k3_range(1) + (k3_range(2) - k3_range(1)) * rand();

            app.C1SeatSlider.Value = c1_range(1) + (c1_range(2) - c1_range(1)) * rand();
            app.C2SuspensionSlider.Value = c2_range(1) + (c2_range(2) - c2_range(1)) * rand();
            app.C3SeatbackfrictionSlider.Value = c3_range(1) + (c3_range(2) - c3_range(1)) * rand();

            app.M1 = app.M1WheelsSlider.Value;
            app.M2 = app.M2ChassisSlider.Value;
            app.M3 = app.M3SeatDriverSlider.Value;

            app.k1 = app.K1Seatx1000Slider.Value * 1000;
            app.k2 = app.K2Suspensionx1000Slider.Value * 1000;
            app.k3 = app.K3Tyrex1000Slider.Value * 1000;

            app.c1 = app.C1SeatSlider.Value;
            app.c2 = app.C2SuspensionSlider.Value;
            app.c3 = app.C3SeatbackfrictionSlider.Value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.input_signal = value;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.simulation_mode = selectedTab;
        end

        % Value changed function: KpSlider
        function KpSliderValueChanged(app, event)
            value = app.KpSlider.Value;
            app.Kp = value;
        end

        % Value changed function: KiSlider
        function KiSliderValueChanged(app, event)
            value = app.KiSlider.Value;
            app.Ki = value;
        end

        % Value changed function: KdSlider
        function KdSliderValueChanged(app, event)
            value = app.KdSlider.Value;
            app.Kd = value;
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            value = app.SaturationSlider.Value;
            app.sat = value;
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.KpSlider.Value = 1920;
            app.KiSlider.Value = 25000;
            app.KdSlider.Value = 2.5;
            app.SaturationSlider.Value = 3000;

            app.Kp = app.KpSlider.Value;
            app.Ki = app.KiSlider.Value;
            app.Kd = app.KdSlider.Value;
            app.sat = app.SaturationSlider.Value;
        end

        % Button pushed function: RandomizeButton_2
        function RandomizeButton_2Pushed(app, event)
            Kp_range = app.KpSlider.Limits;
            Ki_range = app.KiSlider.Limits;
            Kd_range = app.KdSlider.Limits;
            sat_range = app.SaturationSlider.Limits;

            app.KpSlider.Value = Kp_range(1) + (Kp_range(2) - Kp_range(1)) * rand();
            app.KiSlider.Value = Ki_range(1) + (Ki_range(2) - Ki_range(1)) * rand();
            app.KdSlider.Value = Kd_range(1) + (Kd_range(2) - Kd_range(1)) * rand();
            app.SaturationSlider.Value = sat_range(1) + (sat_range(2) - sat_range(1)) * rand();

            app.Kp = app.KpSlider.Value;
            app.Ki = app.KiSlider.Value;
            app.Kd = app.KdSlider.Value;
            app.sat = app.SaturationSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DOFQuarterCarModelbyMSMoudallalLabel = uilabel(app.GridLayout2);
            app.DOFQuarterCarModelbyMSMoudallalLabel.Text = '3DOF Quarter Car Model (by MS Moudallal)';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PassiveSuspensionTab = uitab(app.TabGroup);
            app.PassiveSuspensionTab.Title = 'Passive Suspension';
            app.GridLayout3 = uigridlayout(app.PassiveSuspensionTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ModelParametersLabel = uilabel(app.GridLayout4);
            app.ModelParametersLabel.Text = 'Model Parameters';
            app.M2ChassisSliderLabel = uilabel(app.GridLayout4);
            app.M2ChassisSliderLabel.Text = 'M2 (Chassis)';
            app.M2ChassisSlider = uislider(app.GridLayout4);
            app.M2ChassisSlider.Limits = [100 350];
            app.M2ChassisSlider.ValueChangedFcn = createCallbackFcn(app, @M2ChassisSliderValueChanged, true);
            app.M2ChassisSlider.Value = 100;
            app.M3SeatDriverSliderLabel = uilabel(app.GridLayout4);
            app.M3SeatDriverSliderLabel.Text = 'M3 (Seat + Driver)';
            app.M3SeatDriverSlider = uislider(app.GridLayout4);
            app.M3SeatDriverSlider.Limits = [60 100];
            app.M3SeatDriverSlider.ValueChangedFcn = createCallbackFcn(app, @M3SeatDriverSliderValueChanged, true);
            app.M3SeatDriverSlider.Value = 60;
            app.K1Seatx1000SliderLabel = uilabel(app.GridLayout4);
            app.K1Seatx1000SliderLabel.Text = 'K1 (Seat) x1000';
            app.K1Seatx1000Slider = uislider(app.GridLayout4);
            app.K1Seatx1000Slider.Limits = [4 12];
            app.K1Seatx1000Slider.ValueChangedFcn = createCallbackFcn(app, @K1Seatx1000SliderValueChanged, true);
            app.K1Seatx1000Slider.Value = 4;
            app.K2Suspensionx1000SliderLabel = uilabel(app.GridLayout4);
            app.K2Suspensionx1000SliderLabel.Text = 'K2 (Suspension) x1000';
            app.K2Suspensionx1000Slider = uislider(app.GridLayout4);
            app.K2Suspensionx1000Slider.Limits = [10 30];
            app.K2Suspensionx1000Slider.ValueChangedFcn = createCallbackFcn(app, @K2Suspensionx1000SliderValueChanged, true);
            app.K2Suspensionx1000Slider.Value = 10;
            app.K3Tyrex1000SliderLabel = uilabel(app.GridLayout4);
            app.K3Tyrex1000SliderLabel.Text = 'K3 (Tyre) x1000';
            app.K3Tyrex1000Slider = uislider(app.GridLayout4);
            app.K3Tyrex1000Slider.Limits = [10 50];
            app.K3Tyrex1000Slider.ValueChangedFcn = createCallbackFcn(app, @K3Tyrex1000SliderValueChanged, true);
            app.K3Tyrex1000Slider.Value = 10;
            app.C1SeatSliderLabel = uilabel(app.GridLayout4);
            app.C1SeatSliderLabel.Text = 'C1 (Seat)';
            app.C1SeatSlider = uislider(app.GridLayout4);
            app.C1SeatSlider.Limits = [500 2000];
            app.C1SeatSlider.ValueChangedFcn = createCallbackFcn(app, @C1SeatSliderValueChanged, true);
            app.C1SeatSlider.Value = 500;
            app.C2SuspensionSliderLabel = uilabel(app.GridLayout4);
            app.C2SuspensionSliderLabel.Text = 'C2 (Suspension)';
            app.C2SuspensionSlider = uislider(app.GridLayout4);
            app.C2SuspensionSlider.Limits = [1000 5000];
            app.C2SuspensionSlider.ValueChangedFcn = createCallbackFcn(app, @C2SuspensionSliderValueChanged, true);
            app.C2SuspensionSlider.Value = 1000;
            app.C3SeatbackfrictionSliderLabel = uilabel(app.GridLayout4);
            app.C3SeatbackfrictionSliderLabel.Text = 'C3 (Seatback friction)';
            app.C3SeatbackfrictionSlider = uislider(app.GridLayout4);
            app.C3SeatbackfrictionSlider.Limits = [50 1000];
            app.C3SeatbackfrictionSlider.ValueChangedFcn = createCallbackFcn(app, @C3SeatbackfrictionSliderValueChanged, true);
            app.C3SeatbackfrictionSlider.Value = 50;
            app.M1WheelsSliderLabel = uilabel(app.GridLayout4);
            app.M1WheelsSliderLabel.Text = 'M1 (Wheels)';
            app.M1WheelsSlider = uislider(app.GridLayout4);
            app.M1WheelsSlider.Limits = [10 40];
            app.M1WheelsSlider.ValueChangedFcn = createCallbackFcn(app, @M1WheelsSliderValueChanged, true);
            app.M1WheelsSlider.Value = 10;
            app.ResetButton = uibutton(app.GridLayout4, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RandomizeButton = uibutton(app.GridLayout4, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.InputSignalRoadProfileLabel = uilabel(app.GridLayout7);
            app.InputSignalRoadProfileLabel.Text = 'Input Signal (Road Profile)';
            app.Label = uilabel(app.GridLayout7);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.GridLayout7);
            app.DropDown.Items = {'Step Response', 'Sine Wave', 'Speed Bump', 'Pot hole', 'Ramp', 'Decline'};
            app.DropDown.ItemsData = [1 2 3 4 5 6];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 1;
            app.GenerateNoiseCheckBox = uicheckbox(app.GridLayout7);
            app.GenerateNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateNoiseCheckBoxValueChanged, true);
            app.GenerateNoiseCheckBox.Text = 'Generate Noise';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.ZPlot = uiaxes(app.GridLayout8);
            title(app.ZPlot, 'z (Input Signal)')
            xlabel(app.ZPlot, 'Time (s)')
            ylabel(app.ZPlot, 'z (m)')
            zlabel(app.ZPlot, 'Z')
            app.Y1Plot = uiaxes(app.GridLayout8);
            title(app.Y1Plot, 'y1 (Wheels displacement)')
            xlabel(app.Y1Plot, 'Time (s)')
            ylabel(app.Y1Plot, 'y1 (m)')
            zlabel(app.Y1Plot, 'Z')
            app.Y2Plot = uiaxes(app.GridLayout8);
            title(app.Y2Plot, 'y2 (Chassis displacement)')
            xlabel(app.Y2Plot, 'Time (s)')
            ylabel(app.Y2Plot, 'y2 (m)')
            zlabel(app.Y2Plot, 'Z')
            app.Y3Plot = uiaxes(app.GridLayout8);
            title(app.Y3Plot, 'y3 (Seat displacement)')
            xlabel(app.Y3Plot, 'Time (s)')
            ylabel(app.Y3Plot, 'y3 (m)')
            zlabel(app.Y3Plot, 'Z')
            app.ActiveSuspensionTab = uitab(app.TabGroup);
            app.ActiveSuspensionTab.Title = 'Active Suspension';
            app.GridLayout9 = uigridlayout(app.ActiveSuspensionTab);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.ModelParametersLabel_2 = uilabel(app.GridLayout11);
            app.ModelParametersLabel_2.Text = 'Model Parameters';
            app.ChangeallmodelparametersinthePassiveSuspensiontabLabel = uilabel(app.GridLayout11);
            app.ChangeallmodelparametersinthePassiveSuspensiontabLabel.Text = 'Change all model parameters in the "Passive Suspension" tab.';
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.ControllerParametersLabel = uilabel(app.GridLayout12);
            app.ControllerParametersLabel.Text = 'Controller Parameters';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = 'This simulation uses a PID controller to stabilize and minimize the acceleration of the seat-driver.';
            app.KpSliderLabel = uilabel(app.GridLayout12);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.GridLayout12);
            app.KpSlider.Limits = [500 10000];
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KpSlider.Value = 500;
            app.KiSliderLabel = uilabel(app.GridLayout12);
            app.KiSliderLabel.Text = 'Ki';
            app.KiSlider = uislider(app.GridLayout12);
            app.KiSlider.Limits = [5000 50000];
            app.KiSlider.ValueChangedFcn = createCallbackFcn(app, @KiSliderValueChanged, true);
            app.KiSlider.Value = 5000;
            app.KdSliderLabel = uilabel(app.GridLayout12);
            app.KdSliderLabel.Text = 'Kd';
            app.KdSlider = uislider(app.GridLayout12);
            app.KdSlider.Limits = [0.5 10];
            app.KdSlider.ValueChangedFcn = createCallbackFcn(app, @KdSliderValueChanged, true);
            app.KdSlider.Value = 0.5;
            app.SaturationSliderLabel = uilabel(app.GridLayout12);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.GridLayout12);
            app.SaturationSlider.Limits = [250 10000];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaturationSlider.Value = 250;
            app.ResetButton_2 = uibutton(app.GridLayout12, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.RandomizeButton_2 = uibutton(app.GridLayout12, 'push');
            app.RandomizeButton_2.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButton_2Pushed, true);
            app.RandomizeButton_2.Text = 'Randomize';
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.InputSignalRoadProfileLabel_2 = uilabel(app.GridLayout13);
            app.InputSignalRoadProfileLabel_2.Text = 'Input Signal (Road Profile)';
            app.ChangeinputsignalconfigurationinthePassiveSuspensiontabLabel = uilabel(app.GridLayout13);
            app.ChangeinputsignalconfigurationinthePassiveSuspensiontabLabel.Text = 'Change input signal configuration in the "Passive Suspension" tab.';
            app.GridLayout14 = uigridlayout(app.GridLayout9);
            app.ZAPlot = uiaxes(app.GridLayout14);
            title(app.ZAPlot, 'z (Input Signal)')
            xlabel(app.ZAPlot, 'Time (s)')
            ylabel(app.ZAPlot, 'z (m)')
            zlabel(app.ZAPlot, 'Z')
            app.YAPlot = uiaxes(app.GridLayout14);
            title(app.YAPlot, 'Displacements')
            xlabel(app.YAPlot, 'Time (s)')
            ylabel(app.YAPlot, 'y (m)')
            zlabel(app.YAPlot, 'Z')
            app.RMSAPlot = uiaxes(app.GridLayout14);
            title(app.RMSAPlot, 'Seat-Driver RMS Acceleration')
            xlabel(app.RMSAPlot, 'Time (s)')
            ylabel(app.RMSAPlot, 'Acceleration (g)')
            zlabel(app.RMSAPlot, 'Z')
            app.FAPlot = uiaxes(app.GridLayout14);
            title(app.FAPlot, 'Active Suspension Force')
            xlabel(app.FAPlot, 'Time (s)')
            ylabel(app.FAPlot, 'Force (N)')
            zlabel(app.FAPlot, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SimulateButton = uibutton(app.GridLayout6, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuarterCarModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end